classdef hamzaYahyaScientificCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        atan                       matlab.ui.control.Button
        asin                       matlab.ui.control.Button
        acos                       matlab.ui.control.Button
        ScientificCalculatorLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        radical                    matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Switch                     matlab.ui.control.Switch
        ln                         matlab.ui.control.Button
        RndButton                  matlab.ui.control.Button
        logButton                  matlab.ui.control.Button
        power                      matlab.ui.control.Button
        squared                    matlab.ui.control.Button
        percentage                 matlab.ui.control.Button
        factorial                  matlab.ui.control.Button
        inv                        matlab.ui.control.Button
        sinButton                  matlab.ui.control.Button
        tanButton                  matlab.ui.control.Button
        cosButton                  matlab.ui.control.Button
        pi                         matlab.ui.control.Button
        expButton                  matlab.ui.control.Button
        ACButton                   matlab.ui.control.Button
        minus                      matlab.ui.control.Button
        divide                     matlab.ui.control.Button
        times                      matlab.ui.control.Button
        add                        matlab.ui.control.Button
        equal                      matlab.ui.control.Button
        dot                        matlab.ui.control.Button
        v0                         matlab.ui.control.Button
        v3                         matlab.ui.control.Button
        v2                         matlab.ui.control.Button
        v1                         matlab.ui.control.Button
        v6                         matlab.ui.control.Button
        v5                         matlab.ui.control.Button
        v4                         matlab.ui.control.Button
        v7                         matlab.ui.control.Button
        v8                         matlab.ui.control.Button
        v9                         matlab.ui.control.Button
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        ContextMenu2               matlab.ui.container.ContextMenu
        Menu_2                     matlab.ui.container.Menu
        Menu2_2                    matlab.ui.container.Menu
    end


    methods (Access = private)
        
    function startupFcn(app)
    app.asinButton.Visible = 'off';
    app.acosButton.Visible = 'off';
    app.atanButton.Visible = 'off';
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RndButton
        function RndButtonPushed(app, event)
                w = [app.Label.Text '(randn)'];
            app.Label.Text  = w;
        end

        % Button pushed function: v9
        function v9Pushed(app, event)
            w=[app.Label.Text '9'];
app.Label.Text = w;
        end

        % Button pushed function: v2
        function v2Pushed(app, event)
            w=[app.Label.Text '2'];
app.Label.Text = w;
        end

        % Button pushed function: ACButton
        function ACButtonPushed(app, event)
               app.Label.Text='';
        end

        % Button pushed function: divide
        function divideButtonPushed(app, event)
                 w=[app.Label.Text '/'];
app.Label.Text = w;
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
                w=[app.Label.Text '+'];
app.Label.Text = w;
        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
             w=[app.Label.Text '-'];
app.Label.Text = w;
        end

        % Button pushed function: times
        function timesButtonPushed(app, event)
                 w=[app.Label.Text '*'];
app.Label.Text = w;   
        end

        % Button pushed function: v8
        function v8ButtonPushed(app, event)
            w=[app.Label.Text '8'];
app.Label.Text = w;
        end

        % Button pushed function: v7
        function v7ButtonPushed(app, event)
            w=[app.Label.Text '7'];
app.Label.Text = w;
        end

        % Button pushed function: v3
        function v3ButtonPushed(app, event)
            w=[app.Label.Text '3'];
app.Label.Text = w;
        end

        % Button pushed function: v1
        function v1ButtonPushed(app, event)
            w=[app.Label.Text '1'];
app.Label.Text = w;
        end

        % Button pushed function: v4
        function v4ButtonPushed(app, event)
            w=[app.Label.Text '4'];
app.Label.Text = w;
        end

        % Button pushed function: v5
        function v5ButtonPushed(app, event)
            w=[app.Label.Text '5'];
app.Label.Text = w;
        end

        % Button pushed function: v6
        function v6ButtonPushed(app, event)
            w=[app.Label.Text '6'];
app.Label.Text = w;
        end

        % Button pushed function: equal
        function equalButtonPushed(app, event)
       switchState = app.Switch.Value;
            if strcmp(switchState, 'On')
                    if contains(app.Label.Text,'/0')
                       app.Label.Text='';
                        w=[app.Label.Text 'error occured retry'];
            app.Label.Text = w;
                    else 
                        result=str2num(app.Label.Text);
                    app.Label.Text='';
                   app.Label.Text=num2str(result);
                        
                    end 
                app.Lamp.Color = [0, 1, 0]; % Green color for ON
            else   
                app.Lamp.Color = [1, 0, 0]; % Red color for OFF
                 app.Label.Text='';
            end            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                      w = [app.Label.Text ')'];
            app.Label.Text  = w;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             w = [app.Label.Text '('];
            app.Label.Text  = w;
        end

        % Button pushed function: dot
        function dotButtonPushed(app, event)
               w = [app.Label.Text '.'];
           app.Label.Text  = w;
        end

        % Button pushed function: pi
        function piButtonPushed(app, event)
               w = [app.Label.Text 'pi'];
           app.Label.Text  = w;
        end

        % Button pushed function: ln
        function lnButtonPushed(app, event)
             w= [app.Label.Text 'log('];
            app.Label.Text  = w;
        end

        % Button pushed function: sinButton
        function sinButtonPushed(app, event)
             w = [app.Label.Text 'sind('];
           app.Label.Text  = w;
        end

        % Button pushed function: cosButton
        function cosButtonPushed(app, event)
             w = [app.Label.Text 'cosd('];
            app.Label.Text  = w;
        end

        % Button pushed function: tanButton
        function tanButtonPushed(app, event)
             w = [app.Label.Text 'tand('];
           app.Label.Text  = w;
        end

        % Button pushed function: radical
        function radicalButtonPushed(app, event)
              w = [app.Label.Text 'sqrt('];
            app.Label.Text  = w;
        end

        % Button pushed function: percentage
        function percentageButtonPushed(app, event)
                w = [app.Label.Text '*(0.01)'];
           app.Label.Text  = w;
        end

        % Button pushed function: squared
        function squaredButtonPushed(app, event)
               w = [app.Label.Text '^(2)'];
           app.Label.Text  = w;
        end

        % Button pushed function: v0
        function v0ButtonPushed(app, event)
                 w = [app.Label.Text '0'];
           app.Label.Text  = w;
        end

        % Button pushed function: expButton
        function expButtonPushed(app, event)
            w = [app.Label.Text 'exp('];
           app.Label.Text  = w;
        end

        % Button pushed function: power
        function powerButtonPushed(app, event)
            w = [app.Label.Text '^('];
           app.Label.Text  = w;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
          
            switchState = app.Switch.Value;
            if strcmp(switchState, 'On')
               
                app.Lamp.Color = [0, 1, 0]; % Green 
            else   
                app.Lamp.Color = [1, 0, 0]; % Red 
                 app.Label.Text='';
            end
 
        end

        % Button pushed function: factorial
        function factorialButtonPushed(app, event)
            w = [app.Label.Text 'factorial('];
           app.Label.Text  = w;
        end

        % Button pushed function: inv
        function invButtonPushed(app, event)

    d = app.inv.Text;
    if contains(d, 'invr')
        app.inv.Text = 'advan'; 
        app.sinButton.Text = 'asin';                                   
        app.cosButton.Text = 'acos';
        app.tanButton.Text = 'atan';
        app.sinButton.ButtonPushedFcn = @(src, event) asinButtonPushed(app, event);
        app.cosButton.ButtonPushedFcn = @(src, event) acosButtonPushed(app, event);
        app.tanButton.ButtonPushedFcn = @(src, event) atanButtonPushed(app, event);
    else 
        app.inv.Text = 'invr'; 
        app.sinButton.Text = 'sin';            
        app.cosButton.Text = 'cos';
        app.tanButton.Text = 'tan';
        app.sinButton.ButtonPushedFcn = @(src, event) sinButtonPushed(app, event);
        app.cosButton.ButtonPushedFcn = @(src, event) cosButtonPushed(app, event);
        app.tanButton.ButtonPushedFcn = @(src, event) tanButtonPushed(app, event);
    end


        end

        % Button pushed function: acos
        function acosButtonPushed(app, event)
             w = [app.Label.Text 'acos('];
    app.Label.Text  = w ;
        end

        % Button pushed function: asin
        function asinButtonPushed(app, event)
             w = [app.Label.Text 'asin('];
    app.Label.Text  = w ;
        end

        % Button pushed function: atan
        function atanButtonPushed(app, event)
             w = [app.Label.Text 'atan('];
    app.Label.Text  = w;
        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
            w = [app.Label.Text 'log10('];
           app.Label.Text  = w;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.v9 = uibutton(app.UIFigure, 'push');
            app.v9.ButtonPushedFcn = createCallbackFcn(app, @v9Pushed, true);
            app.v9.Text = '9';
            app.v8 = uibutton(app.UIFigure, 'push');
            app.v8.ButtonPushedFcn = createCallbackFcn(app, @v8ButtonPushed, true);
            app.v8.Text = '8';
            app.v7 = uibutton(app.UIFigure, 'push');
            app.v7.ButtonPushedFcn = createCallbackFcn(app, @v7ButtonPushed, true);
            app.v7.Text = '7';
            app.v4 = uibutton(app.UIFigure, 'push');
            app.v4.ButtonPushedFcn = createCallbackFcn(app, @v4ButtonPushed, true);
            app.v4.Text = '4';
            app.v5 = uibutton(app.UIFigure, 'push');
            app.v5.ButtonPushedFcn = createCallbackFcn(app, @v5ButtonPushed, true);
            app.v5.Text = '5';
            app.v6 = uibutton(app.UIFigure, 'push');
            app.v6.ButtonPushedFcn = createCallbackFcn(app, @v6ButtonPushed, true);
            app.v6.Text = '6';
            app.v1 = uibutton(app.UIFigure, 'push');
            app.v1.ButtonPushedFcn = createCallbackFcn(app, @v1ButtonPushed, true);
            app.v1.Text = '1';
            app.v2 = uibutton(app.UIFigure, 'push');
            app.v2.ButtonPushedFcn = createCallbackFcn(app, @v2Pushed, true);
            app.v2.Text = '2';
            app.v3 = uibutton(app.UIFigure, 'push');
            app.v3.ButtonPushedFcn = createCallbackFcn(app, @v3ButtonPushed, true);
            app.v3.Text = '3';
            app.v0 = uibutton(app.UIFigure, 'push');
            app.v0.ButtonPushedFcn = createCallbackFcn(app, @v0ButtonPushed, true);
            app.v0.Text = '0';
            app.dot = uibutton(app.UIFigure, 'push');
            app.dot.ButtonPushedFcn = createCallbackFcn(app, @dotButtonPushed, true);
            app.dot.Text = '.';
            app.equal = uibutton(app.UIFigure, 'push');
            app.equal.ButtonPushedFcn = createCallbackFcn(app, @equalButtonPushed, true);
            app.equal.Text = '=';
            app.add = uibutton(app.UIFigure, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.times = uibutton(app.UIFigure, 'push');
            app.times.ButtonPushedFcn = createCallbackFcn(app, @timesButtonPushed, true);
            app.times.Text = 'x';
            app.divide = uibutton(app.UIFigure, 'push');
            app.divide.ButtonPushedFcn = createCallbackFcn(app, @divideButtonPushed, true);
            app.divide.Text = '÷';
            app.minus = uibutton(app.UIFigure, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '-';
            app.ACButton = uibutton(app.UIFigure, 'push');
            app.ACButton.ButtonPushedFcn = createCallbackFcn(app, @ACButtonPushed, true);
            app.ACButton.Text = 'AC';
            app.expButton = uibutton(app.UIFigure, 'push');
            app.expButton.ButtonPushedFcn = createCallbackFcn(app, @expButtonPushed, true);
            app.expButton.Text = 'exp';
            app.pi = uibutton(app.UIFigure, 'push');
            app.pi.ButtonPushedFcn = createCallbackFcn(app, @piButtonPushed, true);
            app.pi.Text = 'π';
            app.cosButton = uibutton(app.UIFigure, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @cosButtonPushed, true);
            app.cosButton.Text = 'cos';
            app.tanButton = uibutton(app.UIFigure, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @tanButtonPushed, true);
            app.tanButton.Text = 'tan';
            app.sinButton = uibutton(app.UIFigure, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinButtonPushed, true);
            app.sinButton.Text = 'sin';
            app.inv = uibutton(app.UIFigure, 'push');
            app.inv.ButtonPushedFcn = createCallbackFcn(app, @invButtonPushed, true);
            app.inv.Text = 'invr';
            app.factorial = uibutton(app.UIFigure, 'push');
            app.factorial.ButtonPushedFcn = createCallbackFcn(app, @factorialButtonPushed, true);
            app.factorial.Text = '!';
            app.percentage = uibutton(app.UIFigure, 'push');
            app.percentage.ButtonPushedFcn = createCallbackFcn(app, @percentageButtonPushed, true);
            app.percentage.Text = '%';
            app.squared = uibutton(app.UIFigure, 'push');
            app.squared.ButtonPushedFcn = createCallbackFcn(app, @squaredButtonPushed, true);
            app.squared.Text = '☐²';
            app.power = uibutton(app.UIFigure, 'push');
            app.power.ButtonPushedFcn = createCallbackFcn(app, @powerButtonPushed, true);
            app.power.Text = '^';
            app.logButton = uibutton(app.UIFigure, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Text = 'log';
            app.RndButton = uibutton(app.UIFigure, 'push');
            app.RndButton.ButtonPushedFcn = createCallbackFcn(app, @RndButtonPushed, true);
            app.RndButton.Text = 'Rnd';
            app.ln = uibutton(app.UIFigure, 'push');
            app.ln.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.ln.Text = 'ln';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Lamp = uilamp(app.UIFigure);
            app.radical = uibutton(app.UIFigure, 'push');
            app.radical.ButtonPushedFcn = createCallbackFcn(app, @radicalButtonPushed, true);
            app.radical.Text = '√';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '(';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ')';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ScientificCalculatorLabel = uilabel(app.UIFigure);
            app.ScientificCalculatorLabel.Text = 'Scientific Calculator';
            app.acos = uibutton(app.UIFigure, 'push');
            app.acos.ButtonPushedFcn = createCallbackFcn(app, @acosButtonPushed, true);
            app.acos.Text = 'acos';
            app.asin = uibutton(app.UIFigure, 'push');
            app.asin.ButtonPushedFcn = createCallbackFcn(app, @asinButtonPushed, true);
            app.asin.Text = 'asin';
            app.atan = uibutton(app.UIFigure, 'push');
            app.atan.ButtonPushedFcn = createCallbackFcn(app, @atanButtonPushed, true);
            app.atan.Text = 'atan';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.v6.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.squared.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hamzaYahyaScientificCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end